classdef Wave_Mapper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SealesMenu                      matlab.ui.container.Menu
        AudioMenu                       matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        D_Expansion                     matlab.ui.control.NumericEditField
        ExpansinEditFieldLabel          matlab.ui.control.Label
        A_Compresion                    matlab.ui.control.Button
        A_Expansion                     matlab.ui.control.Button
        D_Compresion                    matlab.ui.control.NumericEditField
        CompresinEditFieldLabel         matlab.ui.control.Label
        B_Inversion                     matlab.ui.control.Button
        Reset                           matlab.ui.control.Button
        B_Rewind                        matlab.ui.control.Button
        B_Stop                          matlab.ui.control.Button
        B_Play                          matlab.ui.control.Button
        B_Seleccion                     matlab.ui.control.Button
        P_Display                       matlab.ui.control.UIAxes
        P_Grafica                       matlab.ui.control.UIAxes
        SALIDAPanel                     matlab.ui.container.Panel
        COLORDEENTRADAColorPickerLabel_2  matlab.ui.control.Label
        CSAL                            matlab.ui.control.ColorPicker
        ESCALAMIENTO                    matlab.ui.control.EditField
        ESCALAMIENTOLabel               matlab.ui.control.Label
        CORRIMIENTO                     matlab.ui.control.EditField
        DESPLAZAMIENTOLabel             matlab.ui.control.Label
        OPERACIONESButton               matlab.ui.control.StateButton
        ESCALARButton                   matlab.ui.control.StateButton
        DESPLAZARButton                 matlab.ui.control.StateButton
        INVERTIRButton                  matlab.ui.control.StateButton
        GSALIDA                         matlab.ui.control.UIAxes
        ENTRADAPanel                    matlab.ui.container.Panel
        COLORDEENTRADAColorPickerLabel  matlab.ui.control.Label
        CENT                            matlab.ui.control.ColorPicker
        SELECCIONARTIPODESEALLabel      matlab.ui.control.Label
        TIPODESENALDropDown             matlab.ui.control.DropDown
        PASOLabel                       matlab.ui.control.Label
        PASO                            matlab.ui.control.EditField
        TIEMPOFINAL                     matlab.ui.control.EditField
        TIEMPOFINALLabel                matlab.ui.control.Label
        TIEMPOINICIAL                   matlab.ui.control.EditField
        TIEMPOINICIALLabel              matlab.ui.control.Label
        INGRESARFUNCINLabel             matlab.ui.control.Label
        GRAFICARButton                  matlab.ui.control.Button
        INGRESARFUNCINTextArea          matlab.ui.control.TextArea
        GENTRADA                        matlab.ui.control.UIAxes
    end

   %% PROGRAMA QUE CONTIENE LA INTERFAZ DE SEÑAL
    properties (Access = private)
        CE = [1 0 0]; % Color por defecto para entrada
        CS = [1 0 0]; % Color por defecto para entrada
    end
    methods (Access = private) 
        function [t, x, tipo] = obtsig(app) % Función de lectura
            try
                % Identificar tipo de señal
                tipo = app.TIPODESENALDropDown.Value; % Continua o Discreta
                % Leer valores de tiempo y paso
                t_inicio = eval(app.TIEMPOINICIAL.Value); % Inicio del tiempo
                t_fin = eval(app.TIEMPOFINAL.Value); % Fin del tiempo
                paso = eval(app.PASO.Value); % Paso entre muestras
                t = t_inicio:paso:t_fin; % Vector de tiempo

                % Obtener la función ingresada
                f = app.INGRESARFUNCINTextArea.Value; % Función ingresada por el usuario   
                x = eval(vectorize(f)); % Evalúa la función para cada t

            catch % Se ejecuta si ocurre un error en el bloque try
                % Si ocurre un error, mostrar ventana emergente
                uialert(app.UIFigure, ['Error al graficar la función: Expresión inválida. Revisa operadores, paréntesis o corchetes y otros errores de sintaxis.'], 'Error de entrada'); % Ventana de error
                % Retorna valores vacios para evitar crasheo
                t = [];
                x = [];
                tipo = '';
            end    
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GRAFICARButton
        function GRAFICARButtonPushed(app, event)
            try % Codigo a ejecutar que pueda generar un error
                [t, x, tipo] = app.obtsig(); % Llama a la función de lectura
                
                if isempty(t) % Revisar si el vector t está vacio
                    return
                end
                % COLOR ENTRADA
                % Si aún no se seleccionó color, definir uno por defecto
                if ~isprop(app, 'CE') || isempty(app.CE) % = true si existe la propiedad; = false sino existe
                    app.ColorSeleccionado = [1 0 0]; % Color inicial rojo
                end
                %-------------------------------------------------------------------------------
                if strcmp(tipo, 'CONTINUA') % Compara los strings con las opciones del Drop Down
                    % Graficar la señal
                    plot(app.GENTRADA, t, x, 'LineWidth', 1.5, 'Color', app.CE); % Grafica en Entrada 
                else
                    stem(app.GENTRADA, t, x, 'filled', 'Color', app.CE); % Grafica en Entrada
                end
                title(app.GENTRADA, 'Señal de entrada');    % Título
                grid(app.GENTRADA, 'on'); % Activa la cuadrícula

            catch % Se ejecuta si ocurre un error en el bloque try
                % Si ocurre un error, mostrar ventana emergente
                uialert(app.UIFigure, ['Error al graficar la función: Expresión inválida. Revisa operadores, paréntesis o corchetes y otros errores de sintaxis.'], 'Error de entrada'); % Ventana de error
            end    
        end

        % Value changed function: INVERTIRButton
        function INVERTIRButtonValueChanged(app, event)
            [t, x, tipo] = app.obtsig % Llama a la función de lectura
            y = fliplr(x); % invierte el orden
            
            if isempty(t) % Revisar si el vector t está vacio
               return
            end
            % COLOR SALIDA
            % Si aún no se seleccionó color, definir uno por defecto
            if ~isprop(app, 'CS') || isempty(app.CS) % = true si existe la propiedad; = false sino existe
               app.ColorSeleccionado = [1 0 0]; % Color inicial rojo
            end
            %-------------------------------------------------------------------------------
            if strcmp(tipo, 'CONTINUA') % Compara los strings con las opciones del Drop Down
               % Graficar la señal
               plot(app.GSALIDA, t, y,'LineWidth', 1.5, 'Color', app.CS); % Grafica en Salida 
            else
               stem(app.GSALIDA, t, y,'filled', 'Color', app.CS); % Grafica en Salida
            end  
            title(app.GSALIDA, 'Señal de invertida'); % Título
            grid(app.GSALIDA, 'on'); % Activa la cuadrícula
        end

        % Value changed function: ESCALARButton
        function ESCALARButtonValueChanged(app, event)
            try % Codigo a ejecutar que pueda generar un error
                [t, ~, tipo] = app.obtsig; % Llama a la función de lectura
                a = eval(app.ESCALAMIENTO.Value);
                y = eval(vectorize(strrep(app.INGRESARFUNCINTextArea.Value, 't', sprintf('(%g*t)', a)))); % Ver TABLA ESCALAMIENTO
                %-----------------------------------------------------------TABLA ESCALAMIENTO----------------------------------------------------------%
                %---------------------------------------------------------------------------------------------------------------------------------------%
                % eval(...)                        | Evalúa el string como sintaxis de MATLAB y genera la señal 'y'                                     %
                % vectorize(...)                   | Convierte operadores como ^, *, / para que funcionen elemento a elemento (.^, .*, ./)              %
                % strrep(...)                      | Reemplaza la variable 't' en la función original por el escalamiento temporal                      %
                % app.INGRESARFUNCINTextArea.Value | Obtiene la función ingresada por el usuario                                                        %
                % 't'                              | Es la subcadena que buscamos reemplazar en la función                                              %
                % sprintf('(%g*t)', a)             | Genera la cadena con el factor de escalamiento 'a'                                                 %
                % En conjunto                      | Esta línea aplica el escalamiento en el tiempo a la función ingresada y evalúa la señal resultante %
                %---------------------------------------------------------------------------------------------------------------------------------------%
                % COLOR SALIDA
                % Si aún no se seleccionó color, definir uno por defecto
                if ~isprop(app, 'CS') || isempty(app.CS) % = true si existe la propiedad; = false sino existe
                    app.ColorSeleccionado = [1 0 0]; % Color inicial rojo
                end
                %-------------------------------------------------------------------------------
                if strcmp(tipo, 'CONTINUA') % Compara los strings con las opciones del Drop Down
                   % Graficar la señal
                   plot(app.GSALIDA, t, y, 'LineWidth', 1.5, 'Color', app.CS); % Grafica en Salida 
                else
                   stem(app.GSALIDA, t, y, 'filled', 'Color', app.CS); % Grafica en Salida
                end  
                title(app.GSALIDA, 'Señal de escalada'); % Título
                grid(app.GSALIDA, 'on'); % Activa la cuadrícula
            catch % Se ejecuta si ocurre un error en el bloque try
                % Si ocurre un error, mostrar ventana emergente
                uialert(app.UIFigure, ['Error al graficar la función: Expresión inválida. Revisa operadores, paréntesis o corchetes y otros errores de sintaxis.'], 'Error de entrada'); % Ventana de error
            end
        end

        % Value changed function: DESPLAZARButton
        function DESPLAZARButtonValueChanged(app, event)
            try % Codigo a ejecutar que pueda generar un error
                [t, ~, tipo] = app.obtsig; % Llama a la función de lectura
                b = eval(app.CORRIMIENTO.Value);
                y = eval(vectorize(strrep(app.INGRESARFUNCINTextArea.Value, 't', sprintf('(t-%g)', b)))); % Ver TABLA DESPLAZAMIENTO
                %----------------------------------------------------------TABLA DESPLAZAMIENTO---------------------------------------------------------%
                %---------------------------------------------------------------------------------------------------------------------------------------%
                % eval(...)                        | Evalúa el string como sintaxis de MATLAB y genera la señal 'y'                                     %
                % vectorize(...)                   | Convierte operadores como ^, *, / para que funcionen elemento a elemento (.^, .*, ./)              %
                % strrep(...)                      | Reemplaza la variable 't' en la función original por el escalamiento temporal                      %
                % app.INGRESARFUNCINTextArea.Value | Obtiene la función ingresada por el usuario                                                        %
                % 't'                              | Es la subcadena que buscamos reemplazar en la función                                              %
                % sprintf('(%g*t)', a)             | Genera la cadena con el factor de escalamiento 'a'                                                 %
                % En conjunto                      | Esta línea aplica el escalamiento en el tiempo a la función ingresada y evalúa la señal resultante %
                %---------------------------------------------------------------------------------------------------------------------------------------%
                % COLOR SALIDA
                % Si aún no se seleccionó color, definir uno por defecto
                if ~isprop(app, 'CS') || isempty(app.CS) % = true si existe la propiedad; = false sino existe
                    app.ColorSeleccionado = [1 0 0]; % Color inicial rojo
                end
                %-------------------------------------------------------------------------------
                if strcmp(tipo, 'CONTINUA') % Compara los strings con las opciones del Drop Down
                   % Graficar la señal
                   plot(app.GSALIDA, t, y, 'LineWidth', 1.5, 'Color', app.CS); % Grafica en Salida 
                else
                   stem(app.GSALIDA, t, y, 'filled', 'Color', app.CS); % Grafica en Salida
                end  
                title(app.GSALIDA, 'Señal de desplazada'); % Título
                grid(app.GSALIDA, 'on'); % Activa la cuadrícula
            catch % Se ejecuta si ocurre un error en el bloque try
                % Si ocurre un error, mostrar ventana emergente
                uialert(app.UIFigure, ['Error al graficar la función: Expresión inválida. Revisa operadores, paréntesis o corchetes y otros errores de sintaxis.'], 'Error de entrada'); % Ventana de error
            end
        end

        % Value changed function: OPERACIONESButton
        function OPERACIONESButtonValueChanged(app, event)
            [t, ~, tipo] = app.obtsig; % Llama a la función de lectura
            a = eval(app.ESCALAMIENTO.Value);
            b = eval(app.CORRIMIENTO.Value);
            y = eval(vectorize(strrep(app.INGRESARFUNCINTextArea.Value, 't', sprintf('(-%g*t-%g)', a, b)))); % Ver TABLA DESPLAZAMIENTO
            % COLOR ENTRADA
            % Si aún no se seleccionó color, definir uno por defecto
            if ~isprop(app, 'CS') || isempty(app.CS) % = true si existe la propiedad; = false sino existe
               app.ColorSeleccionado = [1 0 0]; % Color inicial rojo
            end
            %-------------------------------------------------------------------------------
            if strcmp(tipo, 'CONTINUA') % Compara los strings con las opciones del Drop Down
               % Graficar la señal
               plot(app.GSALIDA, t, y, 'LineWidth', 1.5, 'Color', app.CS); % Grafica en Salida 
            else
               stem(app.GSALIDA, t, y, 'filled', 'Color', app.CS); % Grafica en Salida
            end  
               title(app.GSALIDA, 'Señal de salida'); % Título
               grid(app.GSALIDA, 'on'); % Activa la cuadrícula
        end

        % Callback function: AudioMenu, Panel
        function AudioMenuSelected(app, event)
          %% BOTÓN QUE CONTIENE LA ACTIVACIÓN DE LAS OPCIONES "SEÑALES" Y "AUDIO"

            % Oculta los paneles de la interfaz principal
            app.ENTRADAPanel.Visible = 'off';
            app.SALIDAPanel.Visible = 'off';

            % Muestra el panel de audio
            app.Panel.Visible = 'on';
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            %%% PANEL DE AUDIO %%%      
        end

        % Button pushed function: B_Seleccion
        function B_SeleccionButtonPushed(app, event)
  
            %% Botón de Selección

    % Pedir que borre la gráfica cuando regrese a este menú
    cla(app.P_Grafica,"reset");
    
    % Mostrar carga inmediatamente
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Cargando...', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');
    drawnow;
    
    % Seleccionar archivo de audio interactivamente
    [archivo, ruta] = uigetfile('*.mp3;*.wav;*.m4a;*.flac;*.aac', 'Seleccione un archivo de audio');

    % Verificar si el usuario canceló la selección
    if isequal(archivo, 0)
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'Ingrese pista', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end

    % Construir la ruta completa del archivo
    ruta_completa = fullfile(ruta, archivo);

    % Cargar el archivo de audio
    [audio, Fs] = audioread(ruta_completa);

    % Optimizar gráfica: reducir puntos si audio es muy grande
    if length(audio) > 100000
        step = ceil(length(audio) / 50000);
        indices = 1:step:length(audio);
        audio_plot = audio(indices);
        time = (indices-1) / Fs;
    else
        audio_plot = audio;
        time = (0:length(audio)-1) / Fs;
    end

    % Datos globales para usar en otros botones
    assignin('base','Pista',audio);
    assignin('base','Frecuencia',Fs);
    assignin('base','Player',audioplayer(audio, Fs));
    
    % GUARDAR AUDIO ORIGINAL Y LIMPIAR EFECTOS ANTERIORES
    assignin('base','AudioOriginal',audio);
    assignin('base','FsOriginal',Fs);
    
    % LIMPIAR AUDIO INVERTIDO SI EXISTE (para empezar desde cero)
    if evalin('base', 'exist(''AudioInvertido'', ''var'')')
        evalin('base', 'clear AudioInvertido');
    end
    
    % RESETEAR VALORES DE COMPRESIÓN/EXPANSIÓN A VALORES POR DEFECTO
    app.D_Compresion.Value = 1.0;  % Valor neutro para compresión
    app.D_Expansion.Value = 1.0;   % Valor neutro para expansión

    % Crear y configurar la gráfica optimizada
    plot(app.P_Grafica, time, audio_plot);
    title(app.P_Grafica, ['Señal de Audio: ' archivo]);
    xlabel(app.P_Grafica, 'Tiempo (s)');
    ylabel(app.P_Grafica, 'Amplitud');
    grid(app.P_Grafica, 'on');
    xlim(app.P_Grafica, [0 time(end)]);

    % Pantalla de play/stop/rewind/search
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Presione Play', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');

        end

        % Button pushed function: B_Play
        function B_PlayButtonPushed(app, event)

            %% Botón de Play
  
    % Verificación rápida
    if ~evalin('base', 'exist(''Player'', ''var'')')
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'No hay archivo', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    player = evalin('base', 'Player');
    
    % Mostrar mensaje inmediatamente
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Reproduciendo...', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');
    drawnow;
    
    % Reproducción optimizada
    if ~isplaying(player)
        try
            % Intentar reanudar si estaba pausado
            resume(player);
        catch
            % Si no se puede reanudar, empezar desde el principio
            play(player);
        end
    else
        % Si ya está reproduciendo, mostrar mensaje
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'Ya se está reproduciendo', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
    end

        end

        % Button pushed function: B_Stop
        function B_StopButtonPushed(app, event)
             
            %% Botón de Stop
    
    % Verificación única
    if ~evalin('base', 'exist(''Player'', ''var'')')
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'No hay archivo', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    player = evalin('base', 'Player');
    
    % Lógica simplificada
    if isplaying(player)
        pause(player);
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'Reproducción pausada', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
    else
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'No hay reproducción activa', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
    end

        end

        % Button pushed function: B_Rewind
        function B_RewindButtonPushed(app, event)
             
            %% Botón de Rewind
   
    % Verificación única
    if ~evalin('base', 'exist(''Player'', ''var'')')
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'No hay archivo', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    % Obtener todo en una secuencia lógica
    player = evalin('base', 'Player');
    audio_actual = evalin('base', 'Pista');
    Fs = evalin('base', 'Frecuencia');
    
    % Detener si está reproduciendo
    if isplaying(player)
        stop(player);
    end
    
    % Crear nuevo audioplayer desde el principio
    assignin('base', 'Player', audioplayer(audio_actual, Fs));

    % Mostrar mensaje
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Reiniciado - Presione Play', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');

        end

        % Button pushed function: B_Inversion
        function B_InversionButtonPushed(app, event)
                  
            %% Botón de Inversión
            
    % Verificar si hay audio cargado
    if ~evalin('base', 'exist(''Player'', ''var'')')
        cla(app.P_Grafica, "reset");
        text(app.P_Grafica, 0.5, 0.5, 'No hay ninguna pista cargada', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        title(app.P_Grafica, '');
        xlabel(app.P_Grafica, '');
        ylabel(app.P_Grafica, '');
        grid(app.P_Grafica, 'off');
        cla(app.P_Display, "reset");
        return;
    end
    
    % Mostrar mensaje de procesamiento inmediato
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Procesando...', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');
    drawnow;
    
    % Obtener audio original y frecuencia
    audio_original = evalin('base', 'AudioOriginal');
    Fs = evalin('base', 'FsOriginal');
    
    % Detener reproducción actual
    player = evalin('base', 'Player');
    if isplaying(player)
        stop(player);
    end
    
    % Verificar el estado actual para determinar qué hacer
    if evalin('base', 'exist(''AudioInvertido'', ''var'')')
        %% SI YA ESTÁ INVERTIDO: VOLVER AL ORIGINAL
        audio_procesado = audio_original;
        titulo_grafica = 'Audio Original Restaurado';
        
        % Limpiar variable de audio invertido
        evalin('base', 'clear AudioInvertido');
        mensaje_display = 'Audio original - Presione Play';
    else
        %% SI NO ESTÁ INVERTIDO: INVERTIR
        audio_procesado = flipud(audio_original);
        titulo_grafica = 'Audio Invertido en el Tiempo';
        
        % Guardar el audio invertido como base para compresión/expansión
        assignin('base', 'AudioInvertido', audio_procesado);
        mensaje_display = 'Audio invertido - Presione Play';
    end
    
    % Optimizar gráfica
    if length(audio_procesado) > 100000
        step = ceil(length(audio_procesado) / 50000);
        indices = 1:step:length(audio_procesado);
        audio_plot = audio_procesado(indices);
        time = (indices-1) / Fs;
    else
        audio_plot = audio_procesado;
        time = (0:length(audio_procesado)-1) / Fs;
    end
    
    % Actualizar la gráfica
    cla(app.P_Grafica, "reset");
    plot(app.P_Grafica, time, audio_plot);
    title(app.P_Grafica, titulo_grafica);
    xlabel(app.P_Grafica, 'Tiempo (s)');
    ylabel(app.P_Grafica, 'Amplitud');
    grid(app.P_Grafica, 'on');
    xlim(app.P_Grafica, [0 time(end)]);

    % Guardar el audio para reproducción
    assignin('base', 'Pista', audio_procesado);
    assignin('base', 'Player', audioplayer(audio_procesado, Fs));
    
    % Mostrar mensaje
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, mensaje_display, ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');

        end

        % Button pushed function: Reset
        function ResetPushed(app, event)
        
            %% Botón de Reset
   
    % Detener reproducción de forma segura
    try
        player = evalin('base', 'Player');
        if isplaying(player)
            stop(player);
        end
    catch
    end
    
    % Limpiar todas las variables globales
    try
        evalin('base', 'clear Pista Frecuencia Player AudioOriginal FsOriginal AudioInvertido');
    catch
    end
    
    % Limpiar interfaces
    cla(app.P_Grafica, "reset");
    cla(app.P_Display, "reset");
    
    % Resetear valores de compresión/expansión a valores por defecto
    app.D_Compresion.Value = 1.0;
    app.D_Expansion.Value = 1.0;
    
    % Configurar estado inicial de la gráfica
    title(app.P_Grafica, 'Gráfica de Audio');
    xlabel(app.P_Grafica, 'Tiempo (s)');
    ylabel(app.P_Grafica, 'Amplitud');
    grid(app.P_Grafica, 'off');
    
    text(app.P_Grafica, 0.5, 0.5, 'No hay audio cargado', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold', 'Color', [0.5 0.5 0.5]);
        
    % Mostrar mensaje en display
    text(app.P_Display, 0.5, 0.5, 'Sistema reiniciado', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');

        end

        % Menu selected function: SealesMenu
        function SealesMenuSelected(app, event)
             % Oculta el panel de audio
             app.Panel.Visible = 'off';
    
             % Muestra los paneles de la interfaz principal
             app.ENTRADAPanel.Visible = 'on';
             app.SALIDAPanel.Visible = 'on';
        end

        % Callback function: CENT
        function CENTValueChanged(app, event)
             app.CE = app.CENT.Value;            
        end

        % Callback function: CSAL
        function CSALValueChanged(app, event)
            app.CS = app.CSAL.Value;            
        end

        % Button pushed function: A_Expansion
        function A_ExpansionButtonPushed(app, event)
                    
            %% Botón de Aplicar Expansión
   
    % Verificar si hay audio cargado
    if ~evalin('base', 'exist(''Player'', ''var'')')
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'No hay archivo', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    % Obtener factor de expansión del cuadro de texto
    factor = app.D_Expansion.Value;
    
    % Validar límites seguros (1.0x a 3.0x)
    if factor < 1.0 || factor > 3.0
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'Factor inválido: 1.0 - 3.0', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    % Mostrar procesamiento
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Expandiendo...', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');
    drawnow;
    
    % Obtener SIEMPRE el audio BASE
    if evalin('base', 'exist(''AudioInvertido'', ''var'')')
        % Si está invertido, usar el audio invertido
        audio_base = evalin('base', 'AudioInvertido');
        tipo_base = 'invertido';
    else
        % Si no está invertido, usar el audio original 
        audio_base = evalin('base', 'AudioOriginal');
        tipo_base = 'original';
    end
    Fs = evalin('base', 'FsOriginal');
    
    % Detener reproducción actual
    try
        player = evalin('base', 'Player');
        if isplaying(player)
            stop(player);
        end
    catch
    end
    
    % Aplicar expansión con interpolación lineal 
    time_base = (0:length(audio_base)-1)';
    time_nuevo = (0:1/factor:length(audio_base)-1)';
    audio_procesado = interp1(time_base, audio_base, time_nuevo, 'linear', 0);
    
    % Optimizar gráfica
    if length(audio_procesado) > 100000
        step = ceil(length(audio_procesado) / 50000);
        indices = 1:step:length(audio_procesado);
        audio_plot = audio_procesado(indices);
        time = (indices-1) / Fs;
    else
        audio_plot = audio_procesado;
        time = (0:length(audio_procesado)-1) / Fs;
    end
    
    % Actualizar gráfica
    cla(app.P_Grafica, "reset");
    plot(app.P_Grafica, time, audio_plot);
    title(app.P_Grafica, ['Audio ' tipo_base ' Expandido: ' num2str(factor) 'x']);
    xlabel(app.P_Grafica, 'Tiempo (s)');
    ylabel(app.P_Grafica, 'Amplitud');
    grid(app.P_Grafica, 'on');
    xlim(app.P_Grafica, [0 time(end)]);
    
    % Guardar audio procesado
    assignin('base', 'Pista', audio_procesado);
    assignin('base', 'Player', audioplayer(audio_procesado, Fs));
    
    % Mostrar mensaje final
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Presione Play', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');

        end

        % Button pushed function: A_Compresion
        function A_CompresionButtonPushed(app, event)
                   
            %% Botón de Aplicar Compresión
    
    % Verificar si hay audio cargado
    if ~evalin('base', 'exist(''Player'', ''var'')')
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'No hay archivo', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    % Obtener factor de compresión del cuadro de texto
    factor = app.D_Compresion.Value;
    
    % Validar límites seguros (0.3x a 1.0x)
    if factor < 0.3 || factor > 1.0
        cla(app.P_Display, "reset");
        text(app.P_Display, 0.5, 0.5, 'Factor inválido: 0.3 - 1.0', ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
            'FontSize', 14, 'FontWeight', 'bold');
        return;
    end
    
    % Mostrar procesamiento
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Comprimiendo...', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');
    drawnow;
    
    % Obtener SIEMPRE el audio BASE SIN EFECTOS (original o invertido puro)
    if evalin('base', 'exist(''AudioInvertido'', ''var'')')
        % Si está invertido, usar el audio invertido PURO como base
        audio_base = evalin('base', 'AudioInvertido');
        tipo_base = 'invertido';
    else
        % Si no está invertido, usar el audio original PURO como base
        audio_base = evalin('base', 'AudioOriginal');
        tipo_base = 'original';
    end
    Fs = evalin('base', 'FsOriginal');
    
    % Detener reproducción actual
    try
        player = evalin('base', 'Player');
        if isplaying(player)
            stop(player);
        end
    catch
    end
    
    % Aplicar compresión con interpolación lineal
    time_base = (0:length(audio_base)-1)';
    time_nuevo = (0:1/factor:length(audio_base)-1)';
    audio_procesado = interp1(time_base, audio_base, time_nuevo, 'linear', 0);
    
    % Optimizar gráfica
    if length(audio_procesado) > 100000
        step = ceil(length(audio_procesado) / 50000);
        indices = 1:step:length(audio_procesado);
        audio_plot = audio_procesado(indices);
        time = (indices-1) / Fs;
    else
        audio_plot = audio_procesado;
        time = (0:length(audio_procesado)-1) / Fs;
    end
    
    % Actualizar gráfica
    cla(app.P_Grafica, "reset");
    plot(app.P_Grafica, time, audio_plot);
    title(app.P_Grafica, ['Audio ' tipo_base ' Comprimido: ' num2str(factor) 'x']);
    xlabel(app.P_Grafica, 'Tiempo (s)');
    ylabel(app.P_Grafica, 'Amplitud');
    grid(app.P_Grafica, 'on');
    xlim(app.P_Grafica, [0 time(end)]);
    
    % Guardar audio procesado
    assignin('base', 'Pista', audio_procesado);
    assignin('base', 'Player', audioplayer(audio_procesado, Fs));
    
    % Mostrar mensaje final
    cla(app.P_Display, "reset");
    text(app.P_Display, 0.5, 0.5, 'Presione Play', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'FontSize', 14, 'FontWeight', 'bold');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SealesMenu = uimenu(app.UIFigure);
            app.SealesMenu.MenuSelectedFcn = createCallbackFcn(app, @SealesMenuSelected, true);
            app.SealesMenu.Text = 'Señales';
            app.AudioMenu = uimenu(app.UIFigure);
            app.AudioMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioMenuSelected, true);
            app.AudioMenu.Text = 'Audio';
            app.ENTRADAPanel = uipanel(app.UIFigure);
            app.ENTRADAPanel.Title = 'ENTRADA';
            app.GENTRADA = uiaxes(app.ENTRADAPanel);
            title(app.GENTRADA, 'ENTRADA')
            xlabel(app.GENTRADA, 'x')
            ylabel(app.GENTRADA, 'f(x)')
            zlabel(app.GENTRADA, 'Z')
            app.INGRESARFUNCINTextArea = uitextarea(app.ENTRADAPanel);
            app.GRAFICARButton = uibutton(app.ENTRADAPanel, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.INGRESARFUNCINLabel = uilabel(app.ENTRADAPanel);
            app.INGRESARFUNCINLabel.Text = 'INGRESAR FUNCIÓN';
            app.TIEMPOINICIALLabel = uilabel(app.ENTRADAPanel);
            app.TIEMPOINICIALLabel.Text = 'TIEMPO INICIAL';
            app.TIEMPOINICIAL = uieditfield(app.ENTRADAPanel, 'text');
            app.TIEMPOFINALLabel = uilabel(app.ENTRADAPanel);
            app.TIEMPOFINALLabel.Text = 'TIEMPO FINAL';
            app.TIEMPOFINAL = uieditfield(app.ENTRADAPanel, 'text');
            app.PASO = uieditfield(app.ENTRADAPanel, 'text');
            app.PASOLabel = uilabel(app.ENTRADAPanel);
            app.PASOLabel.Text = 'PASO';
            app.TIPODESENALDropDown = uidropdown(app.ENTRADAPanel);
            app.TIPODESENALDropDown.Items = {'CONTINUA', 'DISCRETA'};
            app.TIPODESENALDropDown.Value = 'CONTINUA';
            app.SELECCIONARTIPODESEALLabel = uilabel(app.ENTRADAPanel);
            app.SELECCIONARTIPODESEALLabel.Text = 'SELECCIONAR TIPO DE SEÑAL';
            app.CENT = uicolorpicker(app.ENTRADAPanel);
            app.CENT.ValueChangedFcn = createCallbackFcn(app, @CENTValueChanged, true);
            app.COLORDEENTRADAColorPickerLabel = uilabel(app.ENTRADAPanel);
            app.COLORDEENTRADAColorPickerLabel.Text = 'COLOR DE ENTRADA';
            app.SALIDAPanel = uipanel(app.UIFigure);
            app.SALIDAPanel.Title = 'SALIDA';
            app.GSALIDA = uiaxes(app.SALIDAPanel);
            title(app.GSALIDA, 'SALIDA')
            xlabel(app.GSALIDA, 'x')
            ylabel(app.GSALIDA, 'f(x)')
            zlabel(app.GSALIDA, 'Z')
            app.INVERTIRButton = uibutton(app.SALIDAPanel, 'state');
            app.INVERTIRButton.ValueChangedFcn = createCallbackFcn(app, @INVERTIRButtonValueChanged, true);
            app.INVERTIRButton.Text = 'INVERTIR';
            app.DESPLAZARButton = uibutton(app.SALIDAPanel, 'state');
            app.DESPLAZARButton.ValueChangedFcn = createCallbackFcn(app, @DESPLAZARButtonValueChanged, true);
            app.DESPLAZARButton.Text = 'DESPLAZAR';
            app.ESCALARButton = uibutton(app.SALIDAPanel, 'state');
            app.ESCALARButton.ValueChangedFcn = createCallbackFcn(app, @ESCALARButtonValueChanged, true);
            app.ESCALARButton.Text = 'ESCALAR';
            app.OPERACIONESButton = uibutton(app.SALIDAPanel, 'state');
            app.OPERACIONESButton.ValueChangedFcn = createCallbackFcn(app, @OPERACIONESButtonValueChanged, true);
            app.OPERACIONESButton.Text = '3 OPERACIONES';
            app.DESPLAZAMIENTOLabel = uilabel(app.SALIDAPanel);
            app.DESPLAZAMIENTOLabel.Text = 'DESPLAZAMIENTO';
            app.CORRIMIENTO = uieditfield(app.SALIDAPanel, 'text');
            app.ESCALAMIENTOLabel = uilabel(app.SALIDAPanel);
            app.ESCALAMIENTOLabel.Text = 'ESCALAMIENTO';
            app.ESCALAMIENTO = uieditfield(app.SALIDAPanel, 'text');
            app.CSAL = uicolorpicker(app.SALIDAPanel);
            app.CSAL.ValueChangedFcn = createCallbackFcn(app, @CSALValueChanged, true);
            app.COLORDEENTRADAColorPickerLabel_2 = uilabel(app.SALIDAPanel);
            app.COLORDEENTRADAColorPickerLabel_2.Text = 'COLOR DE SALIDA';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.Panel.ButtonDownFcn = createCallbackFcn(app, @AudioMenuSelected, true);
            app.P_Grafica = uiaxes(app.Panel);
            app.P_Grafica.XTick = [];
            app.P_Grafica.YTick = [];
            app.P_Display = uiaxes(app.Panel);
            app.P_Display.XTick = [];
            app.P_Display.YTick = [];
            app.B_Seleccion = uibutton(app.Panel, 'push');
            app.B_Seleccion.ButtonPushedFcn = createCallbackFcn(app, @B_SeleccionButtonPushed, true);
            app.B_Seleccion.Text = 'Seleccionar Audio';
            app.B_Play = uibutton(app.Panel, 'push');
            app.B_Play.ButtonPushedFcn = createCallbackFcn(app, @B_PlayButtonPushed, true);
            app.B_Play.Text = 'Play';
            app.B_Stop = uibutton(app.Panel, 'push');
            app.B_Stop.ButtonPushedFcn = createCallbackFcn(app, @B_StopButtonPushed, true);
            app.B_Stop.Text = 'Stop';
            app.B_Rewind = uibutton(app.Panel, 'push');
            app.B_Rewind.ButtonPushedFcn = createCallbackFcn(app, @B_RewindButtonPushed, true);
            app.B_Rewind.Text = 'Rewind';
            app.Reset = uibutton(app.Panel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetPushed, true);
            app.Reset.Text = 'Reset';
            app.B_Inversion = uibutton(app.Panel, 'push');
            app.B_Inversion.ButtonPushedFcn = createCallbackFcn(app, @B_InversionButtonPushed, true);
            app.B_Inversion.Text = 'Reproducir al revés';
            app.CompresinEditFieldLabel = uilabel(app.Panel);
            app.CompresinEditFieldLabel.Text = 'Compresión';
            app.D_Compresion = uieditfield(app.Panel, 'numeric');
            app.A_Expansion = uibutton(app.Panel, 'push');
            app.A_Expansion.ButtonPushedFcn = createCallbackFcn(app, @A_ExpansionButtonPushed, true);
            app.A_Expansion.Text = 'Aplicar';
            app.A_Compresion = uibutton(app.Panel, 'push');
            app.A_Compresion.ButtonPushedFcn = createCallbackFcn(app, @A_CompresionButtonPushed, true);
            app.A_Compresion.Text = 'Aplicar';
            app.ExpansinEditFieldLabel = uilabel(app.Panel);
            app.ExpansinEditFieldLabel.Text = 'Expansión';
            app.D_Expansion = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wave_Mapper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end